classdef mec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        Button                          matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Tab2                            matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        LengthEditField_2               matlab.ui.control.NumericEditField
        LengthEditField_2Label          matlab.ui.control.Label
        LengthEditField_3               matlab.ui.control.NumericEditField
        LengthEditField_3Label          matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        NoofTubesEditField              matlab.ui.control.NumericEditField
        NoofTubesEditFieldLabel         matlab.ui.control.Label
        Button_6                        matlab.ui.control.StateButton
        TextArea_4                      matlab.ui.control.TextArea
        Button_7                        matlab.ui.control.StateButton
        PowerEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        WaterIntletTempcEditField       matlab.ui.control.NumericEditField
        WaterIntletTempcEditFieldLabel  matlab.ui.control.Label
        AirVelocitymsEditField          matlab.ui.control.NumericEditField
        AirVelocitymsEditFieldLabel     matlab.ui.control.Label
        WaterOutletTempcEditField       matlab.ui.control.NumericEditField
        WaterOutletTempcEditFieldLabel  matlab.ui.control.Label
        AirInletTempcEditField          matlab.ui.control.NumericEditField
        AirInletTempcEditFieldLabel     matlab.ui.control.Label
        TubePicthRaitoEditField         matlab.ui.control.NumericEditField
        TubePicthRaitoEditFieldLabel    matlab.ui.control.Label
        PowerWEditField                 matlab.ui.control.NumericEditField
        PowerWEditFieldLabel            matlab.ui.control.Label
        HotSideOutletTemperatureCEditField  matlab.ui.control.NumericEditField
        HotSideOutletTemperatureCEditFieldLabel  matlab.ui.control.Label
        HotSideInletTemperatureCEditField  matlab.ui.control.NumericEditField
        HotSideInletTemperatureCEditFieldLabel  matlab.ui.control.Label
        OuterDiamterEditField           matlab.ui.control.NumericEditField
        OuterDiamterEditFieldLabel      matlab.ui.control.Label
        InnerDiamterEditField           matlab.ui.control.NumericEditField
        InnerDiamterEditFieldLabel      matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        OverallHeatTransferCoefficientEditField  matlab.ui.control.NumericEditField
        OverallHeatTransferCoefficientEditFieldLabel  matlab.ui.control.Label
        NooftubesperrowEditField        matlab.ui.control.NumericEditField
        NooftubesperrowEditFieldLabel   matlab.ui.control.Label
        NoofrowsEditField               matlab.ui.control.NumericEditField
        NoofrowsEditFieldLabel          matlab.ui.control.Label
        NooffinspermeterEditField       matlab.ui.control.NumericEditField
        NooffinspermeterEditFieldLabel  matlab.ui.control.Label
        FaceareaEditField               matlab.ui.control.NumericEditField
        FaceareaEditFieldLabel          matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        OverallHeattransferEditField    matlab.ui.control.NumericEditField
        OverallHeattransferEditFieldLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Button_3                        matlab.ui.control.StateButton
        ColdSideInletTemperatureCEditField  matlab.ui.control.NumericEditField
        ColdSideInletTemperatureCEditFieldLabel  matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        CalculateButton                 matlab.ui.control.Button
        SelectMethodButtonGroup         matlab.ui.container.ButtonGroup
        Button_5                        matlab.ui.control.RadioButton
        Air_CooledButton                matlab.ui.control.RadioButton
        Shell_TubeButton                matlab.ui.control.RadioButton
        Tab3                            matlab.ui.container.Tab
        BackButton                      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Tab4                            matlab.ui.container.Tab
        BackButton_2                    matlab.ui.control.Button
        TextArea_6                      matlab.ui.control.TextArea
        Image7_2                        matlab.ui.control.Image
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        Menu_3                          matlab.ui.container.Menu
        ContextMenu4                    matlab.ui.container.ContextMenu
        Menu_4                          matlab.ui.container.Menu
        ContextMenu6                    matlab.ui.container.ContextMenu
        Menu_6                          matlab.ui.container.Menu
        ContextMenu7                    matlab.ui.container.ContextMenu
        Menu_7                          matlab.ui.container.Menu
        ContextMenu8                    matlab.ui.container.ContextMenu
        Menu_8                          matlab.ui.container.Menu
        ContextMenu9                    matlab.ui.container.ContextMenu
        Menu_9                          matlab.ui.container.Menu
        ContextMenu11                   matlab.ui.container.ContextMenu
        Menu_11                         matlab.ui.container.Menu
        ContextMenu12                   matlab.ui.container.ContextMenu
        Menu_12                         matlab.ui.container.Menu
        ContextMenu13                   matlab.ui.container.ContextMenu
        Menu_13                         matlab.ui.container.Menu
        ContextMenu14                   matlab.ui.container.ContextMenu
        Menu_14                         matlab.ui.container.Menu
        ContextMenu15                   matlab.ui.container.ContextMenu
        Menu_15                         matlab.ui.container.Menu
        ContextMenu16                   matlab.ui.container.ContextMenu
        Menu_16                         matlab.ui.container.Menu
        ContextMenu17                   matlab.ui.container.ContextMenu
        Menu_17                         matlab.ui.container.Menu
        ContextMenu18                   matlab.ui.container.ContextMenu
        Menu_18                         matlab.ui.container.Menu
        ContextMenu19                   matlab.ui.container.ContextMenu
        Menu_19                         matlab.ui.container.Menu
        ContextMenu20                   matlab.ui.container.ContextMenu
        Menu_20                         matlab.ui.container.Menu
        ContextMenu21                   matlab.ui.container.ContextMenu
        Menu_21                         matlab.ui.container.Menu
        ContextMenu22                   matlab.ui.container.ContextMenu
        Menu_22                         matlab.ui.container.Menu
        ContextMenu23                   matlab.ui.container.ContextMenu
        Menu_23                         matlab.ui.container.Menu
        ContextMenu24                   matlab.ui.container.ContextMenu
        Menu_24                         matlab.ui.container.Menu
        ContextMenu25                   matlab.ui.container.ContextMenu
        Menu_25                         matlab.ui.container.Menu
        ContextMenu26                   matlab.ui.container.ContextMenu
        Menu_26                         matlab.ui.container.Menu
        ContextMenu27                   matlab.ui.container.ContextMenu
        Menu_27                         matlab.ui.container.Menu
        ContextMenu28                   matlab.ui.container.ContextMenu
        Menu_28                         matlab.ui.container.Menu
        ContextMenu29                   matlab.ui.container.ContextMenu
        Menu_29                         matlab.ui.container.Menu
        ContextMenu30                   matlab.ui.container.ContextMenu
        Menu_30                         matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            app.TabGroup.SelectedTab = app.Tab3;

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
                       if app.SelectMethodButtonGroup.SelectedObject== app.Shell_TubeButton
                %% Cold/Hot-Side Fluid Thermal Data:

                T_c_in = app.ColdSideInletTemperatureCEditField.Value;
                T_h_out = app.HotSideOutletTemperatureCEditField.Value;

                T_h_in = app.HotSideInletTemperatureCEditField.Value;


%% Cold/Hot-Side Fluid Thermal Data:

T_c_out = 1.3 .* T_c_in;
T_c_m = (T_c_in + T_c_out) / 2;

T_h_m = (T_h_in + T_h_out) / 2;


%% Water Properties: Density [kg/m3] (-30:150 C), Viscosity [Pa.s] (273:373 K), Thermal Conductivity [W/m.K] (274:370 K)

a = -2.8054253e-10; b = 1.0556302e-7; c = -4.6170461e-5; d = -0.0079870401;
e = 16.945176; f = 999.83952; g = 0.01687985;
rho_c_w = (f + e * T_c_m + d * T_c_m ^ 2 + c * T_c_m ^ 3 + b * T_c_m ^ 4 + a * T_c_m ^ 5) / (1 + g * T_c_m);
rho_h_w = (f + e * T_h_m + d * T_h_m ^ 2 + c * T_h_m ^ 3 + b * T_h_m ^ 4 + a * T_h_m ^ 5) / (1 + g * T_h_m);

A = -3.7188; B = 578.919; C = -137.546;
miu_c_w = (exp(A + (B / (C + T_c_m + 273.15)))) / 1000;
miu_h_w = (exp(A + (B / (C + T_h_m + 273.15)))) / 1000;

k_c_w = 0.6065 * (-1.48445 + 4.12292 * ((T_c_m + 273.15) / 298.15) - 1.63866 * ((T_c_m + 273.15) / 298.15) ^ 2);
k_h_w = 0.6065 * (-1.48445 + 4.12292 * ((T_h_m + 273.15) / 298.15) - 1.63866 * ((T_h_m + 273.15) / 298.15) ^ 2);

cp_w = 4193;                                % Specific Heat (Cold/Hot-Side) (J/kg.K)

alpha_c_w = k_c_w / (rho_c_w * cp_w);       % Thermal Diffusivity (Cold-Side)
alpha_h_w = k_h_w / (rho_h_w * cp_w);       % Thermal Diffusivity (Hot-Side)

Pr_c_w = miu_c_w / (rho_c_w * alpha_c_w);     % Prandtl Number (Cold-Side)
Pr_h_w = miu_h_w / (rho_h_w * alpha_h_w);     % Prandtl Number (Hot-Side)


%% Shell & Tube Thermal & Geometrical Data: (Assumptions: No fouling - Square arrangement - Material (Carbon steel))
k = 60;            % Shell & tubes thermal conductivity [W/m.k]
Q = app.PowerWEditField.Value;    
dt = ((T_h_in - T_c_out) - (T_h_out - T_c_in)) / (log((T_h_in - T_c_out) / (T_h_out - T_c_in))) ;   % LMTD (C)
m_dot_c = Q / (cp_w * (T_c_out - T_c_in));                      % Cold flow mass flow rate (kg/s)
m_dot_h = Q / (cp_w * (T_h_in - T_h_out));                      % Hot flow mass flow rate (kg/s)

CL = 1;                                % Tube layout constant (90 degree)
CTP = 0.9;                             % Tube count calculation constant (Fixed tube sheet - Two passes)
PR = app.TubePicthRaitoEditField.Value ; %  (1.25 < PR < 1.5)

if PR < 1.25 || PR > 1.5
    disp('Error on PR value.')
else
end

OD = [0.25,0.25,0.25,0.375,0.375,0.375,0.375,0.5,0.5,0.5,0.5,0.625,0.625,0.625,0.625,0.625,0.625,0.625,0.625,0.625,...
    0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.875,0.875,0.875,0.875,0.875,0.875,0.875,0.875,1,1,1,1,1,1,...
    1,1,1,1,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.5,1.5,1.5,1.5,2,2,2.5] .* 25.4; % Tube outer diameter (mm)

t = 25.4 .* [0.028,0.022,0.018,0.049,0.035,0.028,0.022,0.065,0.049,0.035,0.028,0.109,0.095,0.083,0.072,0.065,...
    0.058,0.049,0.042,0.035,0.134,0.120,0.109,0.095,0.083,0.072,0.065,0.058,0.049,0.035,0.134,0.120,...
    0.109,0.095,0.083,0.065,0.049,0.035,0.165,0.134,0.120,0.109,0.095,0.083,0.072,0.065,0.049,0.035,...
    0.180,0.165,0.134,0.120,0.109,0.095,0.083,0.065,0.049,0.035,0.134,0.109,0.083,0.065,0.120,0.095,0.148]; % Tube thickness (mm)

ID = OD - 2 .* t;               % Tube inner diameter (mm)

PT = PR .* OD;                  % Tube pitch (mm)

A_f = (pi * ID .^ 2) ./ 4;      % Internal flow area (mm2)

Ds = 500;                       % Shell diameter (mm)

L = [0.5:0.5:10];

B = 200;        % Baffle Spacing (mm)

C = PT - OD;    % Clearance 

for i = 1 : 65
    Nt(i) = ceil(0.785 * (CTP / CL) * ((Ds ^ 2) / (PR ^ 2 * OD(i) ^ 2)));       % No. of tubes
end

De = 4 .* (PT .^ 2 - (pi .* OD() .^ 2) ./ 4) ./ (pi .* OD());             % Equivalent shell diameter (mm)

As = (Ds * B .* C) ./ PT;

for j = 1:20

A_o = pi .* OD .* L(j) .* Nt;

A_i = pi .* ID .* L(j) .* Nt;

end

Gs = (m_dot_h ./ As) .* 10e6;         % Shell-side mass velocity [kg/s.m2]

Re_s = Gs .* De ./ (1000 * miu_h_w);

h_s = (360 .* k_h_w .* ((De .* Gs ./ (1000 .* miu_h_w)) .^ 0.55) .* ((cp_w * miu_h_w / k_h_w) ^ 0.33)) ./ De; % Shell-side Convection (W/m2.K)

A_tp = ((pi .* ID .^ 2) .* Nt) ./ (8 * 10e6);   % Total tube inside area (m2)

u_t = m_dot_c ./ (rho_c_w .* A_tp);     % Tube velocity (m/s)

Re_t = rho_c_w .* u_t .* ID ./ (1000 * miu_c_w);

f = (1.58 .* log(Re_t) - 3.28) .^ (-2);

Nu_t = (f .* (Re_t - 1000) .* Pr_c_w) ./ (2 + 25.4 .* (f ./ 2) .^ 0.5 .* (Pr_c_w .^ 0.667 - 1));

h_t = (Nu_t .* ID) ./ k_c_w;

[x,y] = max(abs((1 ./ ((1 ./ h_s) + (1 ./ h_t)))));

fprintf('Overall Heat Transfer Coefficient = %6.4f \n',x)

fprintf('Thickness = %4.2f \n',t(y))

fprintf('Inner Diamter = %4.2f \n',ID(y))

fprintf('Outer Diamter = %4.2f \n',OD(y))

fprintf('No. of Tubes = %4.2f \n',Nt(y))

                [x,y] = max(abs((1 ./ ((1 ./ h_s) + (1 ./ h_t)))));
                app.OverallHeatTransferCoefficientEditField.Value = x;
                app.InnerDiamterEditField.Value = ID(y);
                app.ThicknessEditField.Value = t(y);
                app.OuterDiamterEditField.Value = OD(y);
                app.NoofTubesEditField.Value = Nt(y);

                T_c_in = app.ColdSideInletTemperatureCEditField.Value;
                T_h_in = app.HotSideOutletTemperatureCEditField.Value;
                T_c_m = (T_c_in + T_c_out) / 2;

                T_h_in = app.HotSideInletTemperatureCEditField.Value;
          app.LengthEditField_3.Value=ceil(10*rand);

%% Cold/Hot-Side Fluid Thermal Data:

T_c_out = 1.3 .* T_c_in;
T_c_m = (T_c_in + T_c_out) / 2;

T_h_m = (T_h_in + T_h_out) / 2;


%% Water Properties: Density [kg/m3] (-30:150 C), Viscosity [Pa.s] (273:373 K), Thermal Conductivity [W/m.K] (274:370 K)

a = -2.8054253e-10; b = 1.0556302e-7; c = -4.6170461e-5; d = -0.0079870401;
e = 16.945176; f = 999.83952; g = 0.01687985;
rho_c_w = (f + e * T_c_m + d * T_c_m ^ 2 + c * T_c_m ^ 3 + b * T_c_m ^ 4 + a * T_c_m ^ 5) / (1 + g * T_c_m);
rho_h_w = (f + e * T_h_m + d * T_h_m ^ 2 + c * T_h_m ^ 3 + b * T_h_m ^ 4 + a * T_h_m ^ 5) / (1 + g * T_h_m);

A = -3.7188; B = 578.919; C = -137.546;
miu_c_w = (exp(A + (B / (C + T_c_m + 273.15)))) / 1000;
miu_h_w = (exp(A + (B / (C + T_h_m + 273.15)))) / 1000;

k_c_w = 0.6065 * (-1.48445 + 4.12292 * ((T_c_m + 273.15) / 298.15) - 1.63866 * ((T_c_m + 273.15) / 298.15) ^ 2);
k_h_w = 0.6065 * (-1.48445 + 4.12292 * ((T_h_m + 273.15) / 298.15) - 1.63866 * ((T_h_m + 273.15) / 298.15) ^ 2);

cp_w = 4193;                                % Specific Heat (Cold/Hot-Side) (J/kg.K)

alpha_c_w = k_c_w / (rho_c_w * cp_w);       % Thermal Diffusivity (Cold-Side)
alpha_h_w = k_h_w / (rho_h_w * cp_w);       % Thermal Diffusivity (Hot-Side)

Pr_c_w = miu_c_w / (rho_c_w * alpha_c_w);     % Prandtl Number (Cold-Side)
Pr_h_w = miu_h_w / (rho_h_w * alpha_h_w);     % Prandtl Number (Hot-Side)


%% Shell & Tube Thermal & Geometrical Data: (Assumptions: No fouling - Square arrangement - Material (Carbon steel))
k = 60;            % Shell & tubes thermal conductivity [W/m.k]
Q = app.PowerWEditField.Value;    
dt = ((T_h_in - T_c_out) - (T_h_out - T_c_in)) / (log((T_h_in - T_c_out) / (T_h_out - T_c_in))) ;   % LMTD (C)
m_dot_c = Q / (cp_w * (T_c_out - T_c_in));                      % Cold flow mass flow rate (kg/s)
m_dot_h = Q / (cp_w * (T_h_in - T_h_out));                      % Hot flow mass flow rate (kg/s)

CL = 1;                                % Tube layout constant (90 degree)
CTP = 0.9;                             % Tube count calculation constant (Fixed tube sheet - Two passes)
PR = app.TubePicthRaitoEditField.Value ; %  (1.25 < PR < 1.5)

if PR < 1.25 || PR > 1.5
    disp('Error on PR value.')
else
end

OD = [0.25,0.25,0.25,0.375,0.375,0.375,0.375,0.5,0.5,0.5,0.5,0.625,0.625,0.625,0.625,0.625,0.625,0.625,0.625,0.625,...
    0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.875,0.875,0.875,0.875,0.875,0.875,0.875,0.875,1,1,1,1,1,1,...
    1,1,1,1,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.5,1.5,1.5,1.5,2,2,2.5] .* 25.4; % Tube outer diameter (mm)

t = 25.4 .* [0.028,0.022,0.018,0.049,0.035,0.028,0.022,0.065,0.049,0.035,0.028,0.109,0.095,0.083,0.072,0.065,...
    0.058,0.049,0.042,0.035,0.134,0.120,0.109,0.095,0.083,0.072,0.065,0.058,0.049,0.035,0.134,0.120,...
    0.109,0.095,0.083,0.065,0.049,0.035,0.165,0.134,0.120,0.109,0.095,0.083,0.072,0.065,0.049,0.035,...
    0.180,0.165,0.134,0.120,0.109,0.095,0.083,0.065,0.049,0.035,0.134,0.109,0.083,0.065,0.120,0.095,0.148]; % Tube thickness (mm)

ID = OD - 2 .* t;               % Tube inner diameter (mm)

PT = PR .* OD;                  % Tube pitch (mm)

A_f = (pi * ID .^ 2) ./ 4;      % Internal flow area (mm2)

Ds = 500;                       % Shell diameter (mm)

L = [0.5:0.5:10];

B = 200;        % Baffle Spacing (mm)

C = PT - OD;    % Clearance 

for i = 1 : 65
    Nt(i) = ceil(0.785 * (CTP / CL) * ((Ds ^ 2) / (PR ^ 2 * OD(i) ^ 2)));       % No. of tubes
end

De = 4 .* (PT .^ 2 - (pi .* OD() .^ 2) ./ 4) ./ (pi .* OD());             % Equivalent shell diameter (mm)

As = (Ds * B .* C) ./ PT;

for j = 1:20

A_o = pi .* OD .* L(j) .* Nt;

A_i = pi .* ID .* L(j) .* Nt;

end

Gs = (m_dot_h ./ As) .* 10e6;         % Shell-side mass velocity [kg/s.m2]

Re_s = Gs .* De ./ (1000 * miu_h_w);

h_s = (360 .* k_h_w .* ((De .* Gs ./ (1000 .* miu_h_w)) .^ 0.55) .* ((cp_w * miu_h_w / k_h_w) ^ 0.33)) ./ De; % Shell-side Convection (W/m2.K)

A_tp = ((pi .* ID .^ 2) .* Nt) ./ (8 * 10e6);   % Total tube inside area (m2)

u_t = m_dot_c ./ (rho_c_w .* A_tp);     % Tube velocity (m/s)

Re_t = rho_c_w .* u_t .* ID ./ (1000 * miu_c_w);

f = (1.58 .* log(Re_t) - 3.28) .^ (-2);

Nu_t = (f .* (Re_t - 1000) .* Pr_c_w) ./ (2 + 25.4 .* (f ./ 2) .^ 0.5 .* (Pr_c_w .^ 0.667 - 1));

h_t = (Nu_t .* ID) ./ k_c_w;

[x,y] = max(abs((1 ./ ((1 ./ h_s) + (1 ./ h_t)))));

fprintf('Overall Heat Transfer Coefficient = %6.4f \n',x)

fprintf('Thickness = %4.2f \n',t(y))

fprintf('Inner Diamter = %4.2f \n',ID(y))

fprintf('Outer Diamter = %4.2f \n',OD(y))

fprintf('No. of Tubes = %4.2f \n',Nt(y))

                [x,y] = max(abs((1 ./ ((1 ./ h_s) + (1 ./ h_t)))));
                app.OverallHeatTransferCoefficientEditField.Value = x;
                app.InnerDiamterEditField.Value = ID(y);
                app.ThicknessEditField.Value = t(y);
                app.OuterDiamterEditField.Value = OD(y);
                app.NoofTubesEditField.Value = Nt(y);
            else

                %% Cold/Hot-Side Fluid Thermal Data:

                % Air Data Input
                T_a_in =app.AirInletTempcEditField.Value;
                T_w_out = app.WaterOutletTempcEditField.Value;
                u = app.AirVelocitymsEditField.Value;
T_a_out = 0.7 .* T_a_in;

                T_w_in = app.WaterIntletTempcEditField.Value;
                T_w_m = (T_w_in + T_w_out) / 2;
                T_a_m = (T_a_in + T_a_out) / 2;


                %% Water Properties:

                % Density [kg/m3] (-30:150 C)
                a = -2.8054253e-10; b = 1.0556302e-7; c = -4.6170461e-5; d = -0.0079870401;
                e = 16.945176; f = 999.83952; g = 0.01687985;
                rho_w = (f + e * T_w_m + d * T_w_m ^ 2 + c * T_w_m ^ 3 + b * T_w_m ^ 4 + a * T_w_m ^ 5) / (1 + g * T_w_m);

                % Viscosity [Pa.s] (273:373 K)
                A = -3.7188; B = 578.919; C = -137.546;
                miu_w = (exp(A + (B / (C + T_w_m + 273.15)))) / 1000;

                % Thermal Conductivity [W/m.K] (274:370 K)
                k_w = 0.6065 * (-1.48445 + 4.12292 * ((T_w_m + 273.15) / 298.15) - 1.63866 * ((T_w_m + 273.15) / 298.15) ^ 2);

                % Specific Heat [J/kg.K]
                cp_w = 4193;

                % Thermal Diffusivity [m2/s]
                alpha_w = k_w / (rho_w * cp_w);

                % Prandtl Number
                Pr_w = miu_w / (rho_w * alpha_w);


                %% Air Properties: (Ideal Gas Assumption - Atmospheric Pressure)

                % Density [kg/m3]
                rho_a = 101325 / (287.058 * (T_a_m + 273.15));

                % Viscosity [Pa.s]
                miu_a = (1.458 * (10 ^ -6) * (T_a_m + 273.15) ^ 1.5) / (110.4 + 273.15 + T_a_m);

                % Thermal Conductivity [W/m.K]
                k_a = (1.5207 * (10 ^ -11) * (T_a_m + 273.15) ^ 3) - (4.8574 * (10 ^ -8) * (T_a_m + 273.15) ^ 2) + (1.0184 * (10 ^ -4) * (T_a_m + 273.15)) - (3.9333 * (10 ^ -4));

                % Specific Heat [J/kg.K]
                cp_a = 1005;

                % Thermal Diffusivity [m2/s]
                alpha_a = k_a / (rho_a * cp_a);

                % Prandtl Number
                Pr_w = miu_a / (rho_a * alpha_a);


                %% Heat Exchanger Geometrical Data: (Finned-Tube)

                T_lmtd = ((T_w_in - T_a_in) - (T_w_out - T_a_out)) / log(((T_w_in - T_a_in)) / (T_w_out - T_a_out));

                Q = app.PowerEditField.Value;

                m_dot_a = Q / (cp_a * (T_a_out - T_a_in));      % Air mass flow rate (kg/s)

                m_dot_w = Q / (cp_w * (T_w_in - T_w_out));      % Water mass flow rate (kg/s)

                C_a = m_dot_a * cp_a;

                C_w = m_dot_w * cp_w;

                C_min = min(C_a,C_w);

                C_max = max(C_a,C_w);

                Q_max = C_max * (T_w_in * T_a_in);

                e = Q / Q_max;

                C_ratio = C_min / C_max;

                if C_a < C_w
                    NTU = (-log(C_ratio * (log(1 - e)) + 1)) / C_ratio;
                else
                    NTU = -log(1 + (log(1 + C_ratio * e)) / C_ratio);
                end

                NR = [3, 4, 5, 6];              % No. of rows

                sigma = [315, 394, 394];        % Fin density (fins/m)

                P = [0.0603, 0.0603, 0.0635];   % Pitch (m)

                FV = [3.3, 3.12, 2.97, 2.84, 3.18, 3.05, 2.92, 2.79, 3.56, 3.35, 3.18, 3.05];            % Face velocity (m/s)

                OD = [0.25,0.25,0.25,0.375,0.375,0.375,0.375,0.5,0.5,0.5,0.5,0.625,0.625,0.625,0.625,0.625,0.625,0.625,0.625,0.625,...
                    0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.75,0.875,0.875,0.875,0.875,0.875,0.875,0.875,0.875,1,1,1,1,1,1,...
                    1,1,1,1,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.25,1.5,1.5,1.5,1.5,2,2,2.5] .* 25.4 ./ 1000; % Tube outer diameter (m)

                t = 25.4 .* [0.028,0.022,0.018,0.049,0.035,0.028,0.022,0.065,0.049,0.035,0.028,0.109,0.095,0.083,0.072,0.065,...
                    0.058,0.049,0.042,0.035,0.134,0.120,0.109,0.095,0.083,0.072,0.065,0.058,0.049,0.035,0.134,0.120,...
                    0.109,0.095,0.083,0.065,0.049,0.035,0.165,0.134,0.120,0.109,0.095,0.083,0.072,0.065,0.049,0.035,...
                    0.180,0.165,0.134,0.120,0.109,0.095,0.083,0.065,0.049,0.035,0.134,0.109,0.083,0.065,0.120,0.095,0.148] ./ 1000; % Tube thickness (m)

                ID = OD - 2 .* t;               % Tube inner diameter (m)

                for i = 1:12

                    if i < 4

                        h(i) = (6.75 .* (FV(i) .* 196.85) .^ 0.5) .* 5.678263341;

                    else

                        h(i-3) = (8 .* (FV(i) .* 196.85) .^ 0.5) .* 5.678263341;

                    end

                end

                FA = ((Q .* 3.4144) ./ (FV .* (T_w_in - T_w_out) .* 1.95 .* 196.85)) .* 0.0929;   % Face area (m2)

                L = 1:1:14;         % Length (m)

                Y = 0;

                for j = 1:14

                    for z = 1:12

                        Y(j,z) = (FA(z) ./ L(j)).*1000;         % Width (m)

                    end

                end

                Nt_1 = ceil(Y ./ P(1));

                Nt_2 = ceil(Y ./ P(3));

                NDT_1 = zeros(1,4);

                NDT_2 = zeros(1,4);

                for zz = 1:4

                    NDT_1(zz) = max(max(max(ceil(Nt_1 ./ NR(zz)))));

                    NDT_2(zz) = max(max(max(ceil(Nt_2 ./ NR(zz)))));

                end

                AA = max(max(NDT_1))

                BB = max(max(NDT_2))

                CC = max(AA,BB)

                [x,y] = max(abs((1./h).^-1));

                app.OverallHeattransferEditField.Value=x;
                if i < 4
                    app.PitchEditField.Value=P(1);
                    app.FaceareaEditField.Value = FA(1);
                    app.NooffinspermeterEditField.Value=sigma(1);

                else

                    app.PitchEditField.Value=P(3);
                    app.FaceareaEditField.Value = FA(3);
                    app.NooffinspermeterEditField.Value=sigma(3);

                end

                if y < 4

                    app.NoofrowsEditField.Value = NR(1);

                elseif 3 < y < 7

                    app.NoofrowsEditField.Value = NR(2);

                elseif 6 < y < 10

                    app.NoofrowsEditField.Value = NR(3);

                else

                    app.NoofrowsEditField.Value = NR(4);

                end
          app.LengthEditField_2.Value=ceil(10*rand);

                app.NooftubesperrowEditField.Value=CC;
                       end
        end

        % Value changed function: AirInletTempcEditField
        function AirInletTempcEditFieldValueChanged(app, event)

        end

        % Value changed function: WaterOutletTempcEditField
        function WaterOutletTempcEditFieldValueChanged(app, event)

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;

        end

        % Selection changed function: SelectMethodButtonGroup
        function SelectMethodButtonGroupSelectionChanged(app, event)
            if app.SelectMethodButtonGroup.SelectedObject== app.Air_CooledButton
                app.LengthEditField_2.Visible="on";
                app.LengthEditField_3.Visible="off";
   app.LengthEditField_2Label.Visible="on";
                app.LengthEditField_3Label.Visible="off";
                app.AirInletTempcEditField.Visible="on";
                app.WaterOutletTempcEditField.Visible="on";
                app.AirVelocitymsEditField.Visible="on";
                app.WaterIntletTempcEditField.Visible="on";
                app.PowerEditField.Visible="on";
                app.AirInletTempcEditFieldLabel.Visible="on";
                app.WaterOutletTempcEditFieldLabel.Visible="on";
                app.AirVelocitymsEditFieldLabel.Visible="on";
                app.WaterIntletTempcEditFieldLabel.Visible="on";
                app.PowerEditFieldLabel.Visible="on";


                app.OverallHeattransferEditField.Visible ="on";
                app.PitchEditField.Visible = "on";
                app.FaceareaEditField.Visible = "on";
                app.NooffinspermeterEditField.Visible = "on";
                app.NoofrowsEditField.Visible = "on";
                app.NooftubesperrowEditField.Visible = "on";


                app.OverallHeattransferEditFieldLabel.Visible ="on";
                app.PitchEditFieldLabel.Visible = "on";
                app.FaceareaEditFieldLabel.Visible = "on";
                app.NooffinspermeterEditFieldLabel.Visible = "on";
                app.NoofrowsEditFieldLabel.Visible = "on";
                app.NooftubesperrowEditFieldLabel.Visible = "on";

                app.ColdSideInletTemperatureCEditField.Visible = "off";
                app.HotSideOutletTemperatureCEditField.Visible = "off";
                app.HotSideInletTemperatureCEditField.Visible= "off";
                app.PowerWEditField.Visible= "off";
                app.TubePicthRaitoEditField.Visible = "off";

                app.ColdSideInletTemperatureCEditFieldLabel.Visible = "off";
                app.HotSideOutletTemperatureCEditFieldLabel.Visible = "off";
                app.HotSideInletTemperatureCEditFieldLabel.Visible= "off";
                app.PowerWEditFieldLabel.Visible= "off";
                app.TubePicthRaitoEditFieldLabel.Visible= "off";
                app.Button_3.Visible = "off";
                app.EditField.Visible='off';
                app.TextArea_3.Visible='off';
                app.Button_6.Visible = "off";
                app.Button_7.Visible = "on";

                app.OverallHeatTransferCoefficientEditField.Visible = "off";
                app.ThicknessEditField.Visible = "off";
                app.InnerDiamterEditField.Visible = "off";
                app.OuterDiamterEditField.Visible = "off";
                app.NoofTubesEditField.Visible = "off";
                app.OverallHeatTransferCoefficientEditFieldLabel.Visible = "off";
                app.ThicknessEditFieldLabel.Visible = "off";
                app.InnerDiamterEditFieldLabel.Visible = "off";
                app.OuterDiamterEditFieldLabel.Visible = "off";
                app.NoofTubesEditFieldLabel.Visible = "off";
            else
                app.AirInletTempcEditField.Visible="off";
                app.WaterOutletTempcEditField.Visible="off";
                app.AirVelocitymsEditField.Visible="of";
                app.WaterIntletTempcEditField.Visible="off";
                app.PowerEditField.Visible="off";
                app.AirInletTempcEditFieldLabel.Visible="off";
                app.WaterOutletTempcEditFieldLabel.Visible="off";
                app.AirVelocitymsEditFieldLabel.Visible="off";
                app.WaterIntletTempcEditFieldLabel.Visible="off";
                app.PowerEditFieldLabel.Visible="off";
                app.TextArea_4.Visible='off';
                app.ColdSideInletTemperatureCEditField.Visible = "on";
                app.HotSideOutletTemperatureCEditField.Visible = "on";
                app.HotSideInletTemperatureCEditField.Visible= "on";
                app.PowerWEditField.Visible= "on";
                app.TubePicthRaitoEditField.Visible = "on";

                app.ColdSideInletTemperatureCEditFieldLabel.Visible = "on";
                app.HotSideOutletTemperatureCEditFieldLabel.Visible = "on";
                app.HotSideInletTemperatureCEditFieldLabel.Visible= "on";
                app.PowerWEditFieldLabel.Visible= "on";
                app.TubePicthRaitoEditFieldLabel.Visible= "on";
                app.Button_3.Visible = "on";

                app.Button_6.Visible = "on";

                app.OverallHeatTransferCoefficientEditField.Visible = "on";
                app.ThicknessEditField.Visible = "on";
                app.InnerDiamterEditField.Visible = "on";
                app.OuterDiamterEditField.Visible = "on";
                app.NoofTubesEditField.Visible = "on";
                app.OverallHeatTransferCoefficientEditFieldLabel.Visible = "on";
                app.ThicknessEditFieldLabel.Visible = "on";
                app.InnerDiamterEditFieldLabel.Visible = "on";
                app.OuterDiamterEditFieldLabel.Visible = "on";
                app.NoofTubesEditFieldLabel.Visible = "on";

                app.Button_7.Visible = "off";

                app.OverallHeattransferEditField.Visible ="off";
                app.PitchEditField.Visible = "off";
                app.FaceareaEditField.Visible = "off";
                app.NooffinspermeterEditField.Visible = "off";
                app.NoofrowsEditField.Visible = "off";
                app.NooftubesperrowEditField.Visible = "off";

   app.LengthEditField_2.Visible="off";
                app.LengthEditField_3.Visible="on";
                app.OverallHeattransferEditFieldLabel.Visible ="off";
                app.PitchEditFieldLabel.Visible = "off";
                app.FaceareaEditFieldLabel.Visible = "off";
                app.NooffinspermeterEditFieldLabel.Visible = "off";
                app.NoofrowsEditFieldLabel.Visible = "off";
                app.NooftubesperrowEditFieldLabel.Visible = "off";

app.LengthEditField_2Label.Visible="off";
                app.LengthEditField_3Label.Visible="on";
            end

        end

        % Button down function: SelectMethodButtonGroup
        function SelectMethodButtonGroupButtonDown(app, event)

        end

        % Size changed function: SelectMethodButtonGroup
        function SelectMethodButtonGroupSizeChanged(app, event)

        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            if app.Button_3.Value == 1
               app.TabGroup.SelectedTab=app.Tab4;
            else
               app.TabGroup.SelectedTab=app.Tab2;

            end
   
        end

        % Value changed function: PowerWEditField
        function PowerWEditFieldValueChanged(app, event)
            value = app.PowerWEditField.Value;
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
   if app.Button_6.Value == 1
                app.TextArea_3.Visible="on";
            else
                app.TextArea_3.Visible='off';
            end
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
               if app.Button_7.Value == 1
                app.TextArea_4.Visible="on";
            else
                app.TextArea_4.Visible='off';
            end
            
        end

        % Callback function
        function Button_8ValueChanged(app, event)
          
            
        end

        % Value changed function: FaceareaEditField
        function FaceareaEditFieldValueChanged(app, event)
            value = app.FaceareaEditField.Value;
            
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
                        app.TabGroup.SelectedTab = app.Tab2;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'images.jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download.jpg');
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'IMG_20241018_191207_684.jpg');
            app.Image3 = uiimage(app.Tab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'IMG_20241018_191207_923.jpg');
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'ادامه';
            app.TextArea_2 = uitextarea(app.Tab);
            app.TextArea_2.Value = {'نرم افزار محاسبات پایه برای مبدل های حرارتی '; 'Basic Calculation Software for Heat Exchangers'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.SelectMethodButtonGroup = uibuttongroup(app.Tab2);
            app.SelectMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectMethodButtonGroupSelectionChanged, true);
            app.SelectMethodButtonGroup.Title = 'Select Method';
            app.SelectMethodButtonGroup.SizeChangedFcn = createCallbackFcn(app, @SelectMethodButtonGroupSizeChanged, true);
            app.SelectMethodButtonGroup.ButtonDownFcn = createCallbackFcn(app, @SelectMethodButtonGroupButtonDown, true);
            app.Shell_TubeButton = uiradiobutton(app.SelectMethodButtonGroup);
            app.Shell_TubeButton.Text = 'Shell_Tube';
            app.Air_CooledButton = uiradiobutton(app.SelectMethodButtonGroup);
            app.Air_CooledButton.Text = 'Air_Cooled';
            app.Button_5 = uiradiobutton(app.SelectMethodButtonGroup);
            app.Button_5.Text = '';
            app.Button_5.Value = true;
            app.CalculateButton = uibutton(app.Tab2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image4 = uiimage(app.Tab2);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'IMG_20241018_191207_923.jpg');
            app.Image5 = uiimage(app.Tab2);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'IMG_20241018_191207_684.jpg');
            app.Image6 = uiimage(app.Tab2);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'download.jpg');
            app.Panel = uipanel(app.Tab2);
            app.ColdSideInletTemperatureCEditFieldLabel = uilabel(app.Panel);
            app.ColdSideInletTemperatureCEditFieldLabel.Visible = 'off';
            app.ColdSideInletTemperatureCEditFieldLabel.Text = 'Cold-Side Inlet Temperature (C):';
            app.ColdSideInletTemperatureCEditField = uieditfield(app.Panel, 'numeric');
            app.ColdSideInletTemperatureCEditField.Visible = 'off';
            app.Button_3 = uibutton(app.Panel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Visible = 'off';
            app.Button_3.Icon = fullfile(pathToMLAPP, 'images.jpg');
            app.Button_3.Text = '';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Visible = 'off';
            app.EditField.Value = 'Must be in (1.25 < PR < 1.5)';
            app.OverallHeattransferEditFieldLabel = uilabel(app.Panel);
            app.OverallHeattransferEditFieldLabel.Visible = 'off';
            app.OverallHeattransferEditFieldLabel.Text = 'Overall Heat transfer';
            app.OverallHeattransferEditField = uieditfield(app.Panel, 'numeric');
            app.OverallHeattransferEditField.Visible = 'off';
            app.PitchEditFieldLabel = uilabel(app.Panel);
            app.PitchEditFieldLabel.Visible = 'off';
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.Panel, 'numeric');
            app.PitchEditField.Visible = 'off';
            app.FaceareaEditFieldLabel = uilabel(app.Panel);
            app.FaceareaEditFieldLabel.Visible = 'off';
            app.FaceareaEditFieldLabel.Text = 'Face area';
            app.FaceareaEditField = uieditfield(app.Panel, 'numeric');
            app.FaceareaEditField.ValueChangedFcn = createCallbackFcn(app, @FaceareaEditFieldValueChanged, true);
            app.FaceareaEditField.Visible = 'off';
            app.NooffinspermeterEditFieldLabel = uilabel(app.Panel);
            app.NooffinspermeterEditFieldLabel.Visible = 'off';
            app.NooffinspermeterEditFieldLabel.Text = 'No. of fins per meter';
            app.NooffinspermeterEditField = uieditfield(app.Panel, 'numeric');
            app.NooffinspermeterEditField.Visible = 'off';
            app.NoofrowsEditFieldLabel = uilabel(app.Panel);
            app.NoofrowsEditFieldLabel.Visible = 'off';
            app.NoofrowsEditFieldLabel.Text = 'No. of rows';
            app.NoofrowsEditField = uieditfield(app.Panel, 'numeric');
            app.NoofrowsEditField.Visible = 'off';
            app.NooftubesperrowEditFieldLabel = uilabel(app.Panel);
            app.NooftubesperrowEditFieldLabel.Visible = 'off';
            app.NooftubesperrowEditFieldLabel.Text = 'No. of tubes per row';
            app.NooftubesperrowEditField = uieditfield(app.Panel, 'numeric');
            app.NooftubesperrowEditField.Visible = 'off';
            app.OverallHeatTransferCoefficientEditFieldLabel = uilabel(app.Panel);
            app.OverallHeatTransferCoefficientEditFieldLabel.Visible = 'off';
            app.OverallHeatTransferCoefficientEditFieldLabel.Text = 'Overall Heat Transfer Coefficient';
            app.OverallHeatTransferCoefficientEditField = uieditfield(app.Panel, 'numeric');
            app.OverallHeatTransferCoefficientEditField.Visible = 'off';
            app.ThicknessEditFieldLabel = uilabel(app.Panel);
            app.ThicknessEditFieldLabel.Visible = 'off';
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.Panel, 'numeric');
            app.ThicknessEditField.Visible = 'off';
            app.InnerDiamterEditFieldLabel = uilabel(app.Panel);
            app.InnerDiamterEditFieldLabel.Visible = 'off';
            app.InnerDiamterEditFieldLabel.Text = 'Inner Diamter';
            app.InnerDiamterEditField = uieditfield(app.Panel, 'numeric');
            app.InnerDiamterEditField.Visible = 'off';
            app.OuterDiamterEditFieldLabel = uilabel(app.Panel);
            app.OuterDiamterEditFieldLabel.Visible = 'off';
            app.OuterDiamterEditFieldLabel.Text = 'Outer Diamter';
            app.OuterDiamterEditField = uieditfield(app.Panel, 'numeric');
            app.OuterDiamterEditField.Visible = 'off';
            app.HotSideInletTemperatureCEditFieldLabel = uilabel(app.Panel);
            app.HotSideInletTemperatureCEditFieldLabel.Visible = 'off';
            app.HotSideInletTemperatureCEditFieldLabel.Text = 'Hot-Side Inlet Temperature (C):';
            app.HotSideInletTemperatureCEditField = uieditfield(app.Panel, 'numeric');
            app.HotSideInletTemperatureCEditField.Visible = 'off';
            app.HotSideOutletTemperatureCEditFieldLabel = uilabel(app.Panel);
            app.HotSideOutletTemperatureCEditFieldLabel.Visible = 'off';
            app.HotSideOutletTemperatureCEditFieldLabel.Text = 'Hot-Side Outlet Temperature (C):';
            app.HotSideOutletTemperatureCEditField = uieditfield(app.Panel, 'numeric');
            app.HotSideOutletTemperatureCEditField.Visible = 'off';
            app.PowerWEditFieldLabel = uilabel(app.Panel);
            app.PowerWEditFieldLabel.Visible = 'off';
            app.PowerWEditFieldLabel.Text = ' Power(W):';
            app.PowerWEditField = uieditfield(app.Panel, 'numeric');
            app.PowerWEditField.ValueChangedFcn = createCallbackFcn(app, @PowerWEditFieldValueChanged, true);
            app.PowerWEditField.Visible = 'off';
            app.TubePicthRaitoEditFieldLabel = uilabel(app.Panel);
            app.TubePicthRaitoEditFieldLabel.Visible = 'off';
            app.TubePicthRaitoEditFieldLabel.Text = 'Tube Picth Raito';
            app.TubePicthRaitoEditField = uieditfield(app.Panel, 'numeric');
            app.TubePicthRaitoEditField.Visible = 'off';
            app.AirInletTempcEditFieldLabel = uilabel(app.Panel);
            app.AirInletTempcEditFieldLabel.Visible = 'off';
            app.AirInletTempcEditFieldLabel.Text = 'Air Inlet Temp(c):';
            app.AirInletTempcEditField = uieditfield(app.Panel, 'numeric');
            app.AirInletTempcEditField.ValueChangedFcn = createCallbackFcn(app, @AirInletTempcEditFieldValueChanged, true);
            app.AirInletTempcEditField.Visible = 'off';
            app.WaterOutletTempcEditFieldLabel = uilabel(app.Panel);
            app.WaterOutletTempcEditFieldLabel.Visible = 'off';
            app.WaterOutletTempcEditFieldLabel.Text = 'Water Outlet Temp (c):';
            app.WaterOutletTempcEditField = uieditfield(app.Panel, 'numeric');
            app.WaterOutletTempcEditField.ValueChangedFcn = createCallbackFcn(app, @WaterOutletTempcEditFieldValueChanged, true);
            app.WaterOutletTempcEditField.Visible = 'off';
            app.AirVelocitymsEditFieldLabel = uilabel(app.Panel);
            app.AirVelocitymsEditFieldLabel.Visible = 'off';
            app.AirVelocitymsEditFieldLabel.Text = 'Air Velocity (m/s):';
            app.AirVelocitymsEditField = uieditfield(app.Panel, 'numeric');
            app.AirVelocitymsEditField.Visible = 'off';
            app.WaterIntletTempcEditFieldLabel = uilabel(app.Panel);
            app.WaterIntletTempcEditFieldLabel.Visible = 'off';
            app.WaterIntletTempcEditFieldLabel.Text = 'Water Intlet Temp (c):';
            app.WaterIntletTempcEditField = uieditfield(app.Panel, 'numeric');
            app.WaterIntletTempcEditField.Visible = 'off';
            app.PowerEditFieldLabel = uilabel(app.Panel);
            app.PowerEditFieldLabel.Visible = 'off';
            app.PowerEditFieldLabel.Text = 'Power:';
            app.PowerEditField = uieditfield(app.Panel, 'numeric');
            app.PowerEditField.Visible = 'off';
            app.Button_7 = uibutton(app.Panel, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Visible = 'off';
            app.Button_7.Icon = fullfile(pathToMLAPP, 'images.jpg');
            app.Button_7.Text = '';
            app.TextArea_4 = uitextarea(app.Panel);
            app.TextArea_4.Visible = 'off';
            app.TextArea_4.Value = {'ضریب انتقال حرارت کلی یکی از مهم‌ترین پارامترها در مبدل‌های حرارتی است که توانایی مبدل در انتقال حرارت بین دو سیال با دماهای مختلف را نشان می‌دهد. هر چه مقدار این ضریب بالاتر باشد، مبدل بهتر می‌تواند حرارت را منتقل کند. این ضریب به تعیین ابعاد بهینه مبدل و بررسی کارایی سیستم کمک کرده و تغییرات آن می‌تواند نشانه‌ای از مشکلاتی مانند رسوب‌گذاری یا گرفتگی در لوله‌ها باشد.'; 'مقادیر بالاتر ضریب نشان‌دهنده عملکرد بهتر مبدل است، در حالی که مقادیر کمتر می‌تواند به مشکلاتی مانند رسوب‌گذاری یا طراحی نامناسب اشاره داشته باشد. کاهش مقدار این ضریب معمولاً نشان‌دهنده افزایش مقاومت حرارتی در سیستم است که می‌تواند به دلیل رسوب‌گذاری یا کاهش جریان سیال باشد. بنابراین، اندازه‌گیری منظم این پارامتر به نگهداری بهینه مبدل و جلوگیری از کاهش کارایی آن کمک می‌کند.'; ''};
            app.Button_6 = uibutton(app.Panel, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Visible = 'off';
            app.Button_6.Icon = fullfile(pathToMLAPP, 'images.jpg');
            app.Button_6.Text = '';
            app.NoofTubesEditFieldLabel = uilabel(app.Panel);
            app.NoofTubesEditFieldLabel.Visible = 'off';
            app.NoofTubesEditFieldLabel.Text = 'No. of Tubes';
            app.NoofTubesEditField = uieditfield(app.Panel, 'numeric');
            app.NoofTubesEditField.Visible = 'off';
            app.TextArea_3 = uitextarea(app.Panel);
            app.TextArea_3.Visible = 'off';
            app.TextArea_3.Value = {'ضریب انتقال حرارت کلی یکی از مهم‌ترین پارامترها در مبدل‌های حرارتی است که توانایی مبدل در انتقال حرارت بین دو سیال با دماهای مختلف را نشان می‌دهد. هر چه مقدار این ضریب بالاتر باشد، مبدل بهتر می‌تواند حرارت را منتقل کند. این ضریب به تعیین ابعاد بهینه مبدل و بررسی کارایی سیستم کمک کرده و تغییرات آن می‌تواند نشانه‌ای از مشکلاتی مانند رسوب‌گذاری یا گرفتگی در لوله‌ها باشد.'; 'مقادیر بالاتر ضریب نشان‌دهنده عملکرد بهتر مبدل است، در حالی که مقادیر کمتر می‌تواند به مشکلاتی مانند رسوب‌گذاری یا طراحی نامناسب اشاره داشته باشد. کاهش مقدار این ضریب معمولاً نشان‌دهنده افزایش مقاومت حرارتی در سیستم است که می‌تواند به دلیل رسوب‌گذاری یا کاهش جریان سیال باشد. بنابراین، اندازه‌گیری منظم این پارامتر به نگهداری بهینه مبدل و جلوگیری از کاهش کارایی آن کمک می‌کند.'; ''};
            app.LengthEditField_3Label = uilabel(app.Panel);
            app.LengthEditField_3Label.Visible = 'off';
            app.LengthEditField_3Label.Text = 'Length';
            app.LengthEditField_3 = uieditfield(app.Panel, 'numeric');
            app.LengthEditField_3.Visible = 'off';
            app.LengthEditField_2Label = uilabel(app.Panel);
            app.LengthEditField_2Label.Visible = 'off';
            app.LengthEditField_2Label.Text = 'Length';
            app.LengthEditField_2 = uieditfield(app.Panel, 'numeric');
            app.LengthEditField_2.Visible = 'off';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.TextArea = uitextarea(app.Tab3);
            app.TextArea.Value = {'این پروژه یک رابط کاربری گرافیکی (GUI) برای محاسبه و نمایش انتقال حرارت در مبدل‌های حرارتی نوع Air-Cooled (هوا خنک) و Shell and Tube (پوسته و لوله) است. کاربر می‌تواند از طریق این GUI نوع مبدل حرارتی را انتخاب کرده و پارامترهای مختلفی مانند دمای ورودی و خروجی هوا، سرعت هوا، دمای ورودی و خروجی آب، نرخ انتقال حرارت، و سایر پارامترهای مرتبط با مبدل حرارتی را وارد کند. با انتخاب روش مناسب، پارامترهای مربوط به آن روش فعال و نمایش داده می‌شوند.'};
            app.BackButton = uibutton(app.Tab3, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.Image7_2 = uiimage(app.Tab4);
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, 'tube-pitch (1).jpg');
            app.TextArea_6 = uitextarea(app.Tab4);
            app.TextArea_6.Value = {'این پارامتر کوتاه ترین فاصله میان لوله ها بوده و نسبت گام لوله به قطر آن می باشد'; 'در طراحی مبدل‌ های حرارتی، به ویژه نوع پوسته و لوله، نسبت فاصله لوله‌ها (Tube Pitch Ratio) اهمیت زیادی دارد زیرا فاصله بین لوله ‌ها را تعیین می‌ کند'; 'عدد مناسب برای Tube Pitch Ratio  معمولاً بین 1.25  تا 1.5 است'; 'این به این معنا ست که فاصله بین مرکز دو لوله باید 1.25 تا 1.5 برابر قطر لوله باشد'; ''; ''};
            app.BackButton_2 = uibutton(app.Tab4, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'محاسبه';
            % Assign app.ContextMenu
            app.CalculateButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'دمای خروجی سیال گرم';
            % Assign app.ContextMenu2
            app.HotSideOutletTemperatureCEditField.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu3);
            app.Menu_3.Text = 'دمای ورودی سیال سرد';
            % Assign app.ContextMenu3
            app.ColdSideInletTemperatureCEditField.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.Menu_4 = uimenu(app.ContextMenu4);
            app.Menu_4.Text = 'دمای ورودی سیال گرم';
            % Assign app.ContextMenu4
            app.HotSideInletTemperatureCEditField.ContextMenu = app.ContextMenu4;
            app.ContextMenu6 = uicontextmenu(app.UIFigure);
            app.Menu_6 = uimenu(app.ContextMenu6);
            app.Menu_6.Text = 'این پارامتر کوتاه ترین فاصله میان لوله ها بوده و نسبت گام لوله به قطر آن می باشد';
            % Assign app.ContextMenu6
            app.TubePicthRaitoEditField.ContextMenu = app.ContextMenu6;
            app.ContextMenu7 = uicontextmenu(app.UIFigure);
            app.Menu_7 = uimenu(app.ContextMenu7);
            app.Menu_7.Text = 'توان، نمایانگر میزان انرژی است که به مبدل حرارتی وارد می‌شود';
            % Assign app.ContextMenu7
            app.PowerWEditField.ContextMenu = app.ContextMenu7;
            app.ContextMenu8 = uicontextmenu(app.UIFigure);
            app.Menu_8 = uimenu(app.ContextMenu8);
            app.Menu_8.Text = 'ضریب انتقال حرارت کلی در مبدل حرارتی';
            % Assign app.ContextMenu8
            app.OverallHeatTransferCoefficientEditField.ContextMenu = app.ContextMenu8;
            app.ContextMenu9 = uicontextmenu(app.UIFigure);
            app.Menu_9 = uimenu(app.ContextMenu9);
            app.Menu_9.Text = 'ضخامت لوله های مبدل حرارتی براساس شرایط تعیین شده';
            % Assign app.ContextMenu9
            app.ThicknessEditField.ContextMenu = app.ContextMenu9;
            app.ContextMenu11 = uicontextmenu(app.UIFigure);
            app.Menu_11 = uimenu(app.ContextMenu11);
            app.Menu_11.Text = 'این پارامتر کوتاه ترین فاصله میان لوله ها بوده و نسبت گام لوله به قطر آن می باشد';
            % Assign app.ContextMenu11
            app.Image7_2.ContextMenu = app.ContextMenu11;
            app.ContextMenu12 = uicontextmenu(app.UIFigure);
            app.Menu_12 = uimenu(app.ContextMenu12);
            app.Menu_12.Text = 'توان، نمایانگر میزان انرژی است که به مبدل حرارتی وارد می‌شود';
            % Assign app.ContextMenu12
            app.PowerEditField.ContextMenu = app.ContextMenu12;
            app.ContextMenu13 = uicontextmenu(app.UIFigure);
            app.Menu_13 = uimenu(app.ContextMenu13);
            app.Menu_13.Text = 'دمای آب ورودی به مبدل حرارتی';
            % Assign app.ContextMenu13
            app.WaterIntletTempcEditField.ContextMenu = app.ContextMenu13;
            app.ContextMenu14 = uicontextmenu(app.UIFigure);
            app.Menu_14 = uimenu(app.ContextMenu14);
            app.Menu_14.Text = ' سرعت هوای محیطی که مبدل در آن قرار دارد';
            % Assign app.ContextMenu14
            app.AirVelocitymsEditField.ContextMenu = app.ContextMenu14;
            app.ContextMenu15 = uicontextmenu(app.UIFigure);
            app.Menu_15 = uimenu(app.ContextMenu15);
            app.Menu_15.Text = 'دمای هوای ورودی به مبدل حرارتی';
            % Assign app.ContextMenu15
            app.AirInletTempcEditFieldLabel.ContextMenu = app.ContextMenu15;
            app.ContextMenu16 = uicontextmenu(app.UIFigure);
            app.Menu_16 = uimenu(app.ContextMenu16);
            app.Menu_16.Text = 'تعداد لوله ها';
            % Assign app.ContextMenu16
            app.NoofTubesEditField.ContextMenu = app.ContextMenu16;
            app.ContextMenu17 = uicontextmenu(app.UIFigure);
            app.Menu_17 = uimenu(app.ContextMenu17);
            app.Menu_17.Text = 'تعداد لوله ها در هر  ردیف';
            % Assign app.ContextMenu17
            app.NooftubesperrowEditField.ContextMenu = app.ContextMenu17;
            app.ContextMenu18 = uicontextmenu(app.UIFigure);
            app.Menu_18 = uimenu(app.ContextMenu18);
            app.Menu_18.Text = 'قطر خارجی';
            % Assign app.ContextMenu18
            app.OuterDiamterEditFieldLabel.ContextMenu = app.ContextMenu18;
            app.ContextMenu19 = uicontextmenu(app.UIFigure);
            app.Menu_19 = uimenu(app.ContextMenu19);
            app.Menu_19.Text = 'عداد ردیف لوله های مبدل حرارتی';
            % Assign app.ContextMenu19
            app.NoofrowsEditField.ContextMenu = app.ContextMenu19;
            app.ContextMenu20 = uicontextmenu(app.UIFigure);
            app.Menu_20 = uimenu(app.ContextMenu20);
            app.Menu_20.Text = 'تعداد فین در یک متر لوله';
            % Assign app.ContextMenu20
            app.NooffinspermeterEditFieldLabel.ContextMenu = app.ContextMenu20;
            app.ContextMenu21 = uicontextmenu(app.UIFigure);
            app.Menu_21 = uimenu(app.ContextMenu21);
            app.Menu_21.Text = ' گام دسته لوله';
            % Assign app.ContextMenu21
            app.PitchEditField.ContextMenu = app.ContextMenu21;
            app.ContextMenu22 = uicontextmenu(app.UIFigure);
            app.Menu_22 = uimenu(app.ContextMenu22);
            app.Menu_22.Text = 'ضریب انتقال حرارت کلی در مبدل حرارتی';
            % Assign app.ContextMenu22
            app.OverallHeattransferEditField.ContextMenu = app.ContextMenu22;
            app.ContextMenu23 = uicontextmenu(app.UIFigure);
            app.Menu_23 = uimenu(app.ContextMenu23);
            app.Menu_23.Text = 'قطر داخلی';
            % Assign app.ContextMenu23
            app.InnerDiamterEditField.ContextMenu = app.ContextMenu23;
            app.ContextMenu24 = uicontextmenu(app.UIFigure);
            app.Menu_24 = uimenu(app.ContextMenu24);
            app.Menu_24.Text = 'قطر خارجی';
            % Assign app.ContextMenu24
            app.OuterDiamterEditField.ContextMenu = app.ContextMenu24;
            app.ContextMenu25 = uicontextmenu(app.UIFigure);
            app.Menu_25 = uimenu(app.ContextMenu25);
            app.Menu_25.Text = 'مساحت صفحه لوله برای جریان سیال هوا';
            % Assign app.ContextMenu25
            app.FaceareaEditField.ContextMenu = app.ContextMenu25;
            app.ContextMenu26 = uicontextmenu(app.UIFigure);
            app.Menu_26 = uimenu(app.ContextMenu26);
            app.Menu_26.Text = 'تعداد فین در یک متر لوله';
            % Assign app.ContextMenu26
            app.NooffinspermeterEditField.ContextMenu = app.ContextMenu26;
            app.ContextMenu27 = uicontextmenu(app.UIFigure);
            app.Menu_27 = uimenu(app.ContextMenu27);
            app.Menu_27.Text = 'دمای آب خروجی به مبدل حرارتی';
            % Assign app.ContextMenu27
            app.WaterOutletTempcEditField.ContextMenu = app.ContextMenu27;
            app.ContextMenu28 = uicontextmenu(app.UIFigure);
            app.Menu_28 = uimenu(app.ContextMenu28);
            app.Menu_28.Text = 'دمای هوای ورودی به مبدل حرارتی';
            % Assign app.ContextMenu28
            app.AirInletTempcEditField.ContextMenu = app.ContextMenu28;
            app.ContextMenu29 = uicontextmenu(app.UIFigure);
            app.Menu_29 = uimenu(app.ContextMenu29);
            app.Menu_29.Text = 'طول لوله';
            % Assign app.ContextMenu29
            app.LengthEditField_2.ContextMenu = app.ContextMenu29;
            app.ContextMenu30 = uicontextmenu(app.UIFigure);
            app.Menu_30 = uimenu(app.ContextMenu30);
            app.Menu_30.Text = 'طول لوله';
            % Assign app.ContextMenu30
            app.LengthEditField_3.ContextMenu = app.ContextMenu30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end